classdef eq_bun < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        PAUSEButton               matlab.ui.control.Button
        PLAYButton                matlab.ui.control.Button
        filter5                   matlab.ui.control.DropDown
        DropDown_5Label           matlab.ui.control.Label
        bw5                       matlab.ui.control.Knob
        f5                        matlab.ui.control.Knob
        filter4                   matlab.ui.control.DropDown
        DropDown_4Label           matlab.ui.control.Label
        bw4                       matlab.ui.control.Knob
        f4                        matlab.ui.control.Knob
        filter3                   matlab.ui.control.DropDown
        DropDown_3Label           matlab.ui.control.Label
        bw3                       matlab.ui.control.Knob
        f3                        matlab.ui.control.Knob
        filter2                   matlab.ui.control.DropDown
        DropDown_2Label           matlab.ui.control.Label
        bw2                       matlab.ui.control.Knob
        f2                        matlab.ui.control.Knob
        filter1                   matlab.ui.control.DropDown
        DropDownLabel             matlab.ui.control.Label
        bw1                       matlab.ui.control.Knob
        f1                        matlab.ui.control.Knob
        RightPanel                matlab.ui.container.Panel
        ChangeTrackDropDown       matlab.ui.control.DropDown
        ChangeTrackDropDownLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        ContextMenu               matlab.ui.container.ContextMenu
        Menu                      matlab.ui.container.Menu
        Menu2                     matlab.ui.container.Menu
        ContextMenu2              matlab.ui.container.ContextMenu
        Menu_2                    matlab.ui.container.Menu
        Menu2_2                   matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        actuallyStopped
        newTrack
        audiofile
        fs
        pushed
        freq1
        bandw1
        freq2
        bandw2
        freq3
        bandw3
        freq4
        bandw4
        freq5
        bandw5        
        filtertype 
        stopped
        needsNewCoefficients
        b
        a
    end
    
    methods (Access = public)
        % gask - dropdown, slect froma bunch of audio files
        %additioanally, add a flag  on the stop button, that pseudo-pauses
        %the audio file
        %p.s. copy the code from playbutton
        
        function updateGraph(app,flag)
            if ~exist('flag','var')
                flag  = 0;
            end


                reader = dsp.AudioFileReader(app.audiofile, ...
             'SamplesPerFrame', 1024);
                
        player = audioDeviceWriter('SampleRate', reader.SampleRate);

            if app.pushed
                [app.audiofile] = selectTrack(app.newTrack);
                app.pushed = 0;
                
                if(flag)
                flag = 0;

        while ~isDone(reader)
            if app.actuallyStopped
                app.actuallyStopped=0;
                release(player)
                release(reader)
            return;
            end
            pause(0);
            audioIn = reader();
            audioIn = audioIn(:,1);
            if app.needsNewCoefficients
                app.needsNewCoefficients = 0;
                [app.b,app.a] = ...
                CreateFiveFilter(audioIn, reader.SampleRate,...
                app.filtertype, ...
                [app.freq1, app.freq2, app.freq3, app.freq4, app.freq5 ],  ...
                [app.bandw1, app.bandw2, app.bandw3, app.bandw4, app.bandw5 ] );
            end
            app.b = real(app.b);
            app.a = real(app.a);
            y1 = filter(app.b(1,:),app.a(1,:),audioIn);
            y2 = filter(app.b(2,:),app.a(2,:),audioIn);
            y3 = filter(app.b(3,:),app.a(3,:),audioIn);
            y4 = filter(app.b(4,:),app.a(4,:),audioIn);
            y5 = filter(app.b(5,:),app.a(5,:),audioIn);
            
            g1 = .2;
            g2 = .2;
            g3 = .2;
            g4 = .2;
            g5 = .2;

            direct_path_gain = 0.05;
            
            audioOut = g1 .* y1 +g2 .* y2 +g3 .* y3 +g4 .* y4 +g5 .* y5 + direct_path_gain .*audioIn;
            if (max(audioOut) > 1)
                audioOut = audioOut/max(audioOut);
            end

            %audioOut = audioOut .* sqrt(hann(length(reader.SamplesPerFrame)))';
%           audioOut = reverb(audioIn);
            A = linspace(0, 0.9, (200)) ;     %rise 35% of signal
            D = linspace(0.9, 0.7,(500));    %drop of 5% of signal
            S = linspace(0.7, 0.7,(100));    %delay of 40% of signal
            R = linspace(0.7, 0,(1024 -800 ));      %drop of 25% of signal
            ADSR = [A D S R];
            audioOut = audioOut .* ADSR';

            Y = abs(fft(audioOut, 2 * app.fs));
            loglog(app.UIAxes,1 : length(Y)/2,Y(1:length(Y)/2));
            ylim(app.UIAxes,[0,150]);
            drawnow limitrate
            pause(1e-100);

            player(audioOut);


        end
        release(reader);
        release(player);
        app.PLAYButton.Text = 'PLAY';
                end
                
                %pass - think how to clear the figure and how to reset
                %knobs, evntually will also have to reset dropdowns
            elseif app.stopped
                while ~strcmp(app.PAUSEButton.Text,'PAUSE')
                    pause(.1);
                end
                app.stopped = 0;
            end
        
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            [app.audiofile] = selectTrack(app.newTrack);
            app.freq1 = 20;
            app.freq2 = 20;
            app.freq3 = 20;
            app.freq4 = 20;
            app.freq5 = 20;
            app.bandw1 = 1;
            app.bandw2 = 1;
            app.bandw3 = 1;
            app.bandw4 = 1;
            app.bandw5 = 1;
            app.pushed = 0;
            app.stopped = 0;
            app.filtertype = ['APF';'APF';'APF';'APF';'APF'];
            app.needsNewCoefficients = 1;
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            %boolvalue = app.PLAYButton.Text ;
            %app.pushed = boolvalue;
            if strcmp(app.PLAYButton.Text,'PLAY')
            app.pushed = 1;
            app.PLAYButton.Text = "STOP";
            updateGraph(app,1);
            
            elseif strcmp(app.PLAYButton.Text,'STOP')
            app.actuallyStopped = 1;
            
            updateGraph(app,1);
            app.PLAYButton.Text = 'PLAY';
            end
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)

            app.stopped =1;
            if strcmp(app.PAUSEButton.Text,'RESUME')
                app.PAUSEButton.Text = 'PAUSE';
            else
                app.PAUSEButton.Text = 'RESUME';
            end


            updateGraph(app);
            startupFcn(app);
            cla(app.UIAxes);
        end

        % Value changing function: f1
        function f1ValueChanging(app, event)
            app.freq1 = event.Value;
            app.needsNewCoefficients = 1;
            updateGraph(app);
        end

        % Value changing function: bw1
        function bw1ValueChanging(app, event)
            app.bandw1 = event.Value;     
            app.needsNewCoefficients = 1;
            updateGraph(app);
        end

        % Value changing function: f2
        function f2ValueChanging(app, event)
            app.freq2 = event.Value;
            app.needsNewCoefficients = 1;
            updateGraph(app);
        end

        % Value changing function: bw2
        function bw2ValueChanging(app, event)
            app.bandw2 = event.Value;
            app.needsNewCoefficients = 1;
            updateGraph(app);

        end

        % Value changing function: f3
        function f3ValueChanging(app, event)
            app.freq3 = event.Value;
            app.needsNewCoefficients = 1;
            updateGraph(app);

        end

        % Value changing function: bw3
        function bw3ValueChanging(app, event)
            app.bandw3 = event.Value;
            app.needsNewCoefficients = 1;
            updateGraph(app);

        end

        % Value changing function: f4
        function f4ValueChanging(app, event)
            app.freq4 = event.Value;
            app.needsNewCoefficients = 1;
            updateGraph(app);

        end

        % Value changing function: bw4
        function bw4ValueChanging(app, event)
            app.bandw4 = event.Value;
            app.needsNewCoefficients = 1;
            updateGraph(app);

        end

        % Value changing function: f5
        function f5ValueChanging(app, event)
            app.freq5 = event.Value;
            app.needsNewCoefficients = 1;
            updateGraph(app);

        end

        % Value changing function: bw5
        function bw5ValueChanging(app, event)
            app.bandw5 = event.Value;
            app.needsNewCoefficients = 1;
            updateGraph(app);

        end

        % Value changed function: filter1
        function filter1ValueChanged(app, event)
            app.filtertype(1,:) = killafonic(app.filter1.Value);
            app.needsNewCoefficients = 1;
            updateGraph(app);
        end

        % Value changed function: filter2
        function filter2ValueChanged(app, event)
            app.filtertype(2,:) = killafonic(app.filter2.Value);
            app.needsNewCoefficients = 1;
            updateGraph(app);

        end

        % Value changed function: filter3
        function filter3ValueChanged(app, event)
            app.filtertype(3,:) = killafonic(app.filter3.Value);
            app.needsNewCoefficients = 1;
            updateGraph(app);

        end

        % Value changed function: filter4
        function filter4ValueChanged(app, event)
            app.filtertype(4,:) = killafonic(app.filter4.Value);
            app.needsNewCoefficients = 1;
            updateGraph(app);

        end

        % Value changed function: filter5
        function filter5ValueChanged(app, event)
            app.filtertype(5,:) = killafonic(app.filter5.Value);
            app.needsNewCoefficients = 1;
            updateGraph(app);

        end

        % Value changed function: ChangeTrackDropDown
        function ChangeTrackDropDownValueChanged(app, event)
            app.newTrack = app.ChangeTrackDropDown.Value;
            [app.audiofile] = selectTrack(app.newTrack);
            updateGraph(app);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {987, 987};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {283, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.f1 = uiknob(app.LeftPanel, 'continuous');
            app.f1.Limits = [20 20000];
            app.f1.MajorTicks = [20 5000 10000 15000 20000];
            app.f1.MajorTickLabels = {'20', '5000', '10000', '15000', '20000'};
            app.f1.ValueChangingFcn = createCallbackFcn(app, @f1ValueChanging, true);
            app.f1.Value = 1000;
            app.bw1 = uiknob(app.LeftPanel, 'continuous');
            app.bw1.Limits = [-5 5];
            app.bw1.ValueChangingFcn = createCallbackFcn(app, @bw1ValueChanging, true);
            app.bw1.Value = 1;
            app.DropDownLabel = uilabel(app.LeftPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.filter1 = uidropdown(app.LeftPanel);
            app.filter1.Items = {'All pass', 'Low pass', 'High pass', 'Band pass ', 'Band stop', 'Low shelf', 'High shelf', 'Peaking EQ'};
            app.filter1.ValueChangedFcn = createCallbackFcn(app, @filter1ValueChanged, true);
            app.filter1.Value = 'All pass';
            app.f2 = uiknob(app.LeftPanel, 'continuous');
            app.f2.Limits = [20 20000];
            app.f2.MajorTicks = [20 5000 10000 15000 20000];
            app.f2.MajorTickLabels = {'20', '5000', '10000', '15000', '20000'};
            app.f2.ValueChangingFcn = createCallbackFcn(app, @f2ValueChanging, true);
            app.f2.Value = 5000;
            app.bw2 = uiknob(app.LeftPanel, 'continuous');
            app.bw2.Limits = [-5 5];
            app.bw2.ValueChangingFcn = createCallbackFcn(app, @bw2ValueChanging, true);
            app.bw2.Value = 1;
            app.DropDown_2Label = uilabel(app.LeftPanel);
            app.DropDown_2Label.Text = 'Drop Down';
            app.filter2 = uidropdown(app.LeftPanel);
            app.filter2.Items = {'All pass', 'Low pass', 'High pass', 'Band pass ', 'Band stop', 'Low shelf', 'High shelf', 'Peaking EQ', '', ' '};
            app.filter2.ValueChangedFcn = createCallbackFcn(app, @filter2ValueChanged, true);
            app.filter2.Value = 'All pass';
            app.f3 = uiknob(app.LeftPanel, 'continuous');
            app.f3.Limits = [20 20000];
            app.f3.MajorTicks = [20 5000 10000 15000 20000];
            app.f3.MajorTickLabels = {'20', '5000', '10000', '15000', '20000'};
            app.f3.ValueChangingFcn = createCallbackFcn(app, @f3ValueChanging, true);
            app.f3.Value = 5000;
            app.bw3 = uiknob(app.LeftPanel, 'continuous');
            app.bw3.Limits = [-5 5];
            app.bw3.ValueChangingFcn = createCallbackFcn(app, @bw3ValueChanging, true);
            app.bw3.Value = 1;
            app.DropDown_3Label = uilabel(app.LeftPanel);
            app.DropDown_3Label.Text = 'Drop Down';
            app.filter3 = uidropdown(app.LeftPanel);
            app.filter3.Items = {'All pass', 'Low pass', 'High pass', 'Band pass ', 'Band stop', 'Low shelf', 'High shelf', 'Peaking EQ'};
            app.filter3.ValueChangedFcn = createCallbackFcn(app, @filter3ValueChanged, true);
            app.filter3.Value = 'All pass';
            app.f4 = uiknob(app.LeftPanel, 'continuous');
            app.f4.Limits = [20 20000];
            app.f4.MajorTicks = [20 5000 10000 15000 20000];
            app.f4.MajorTickLabels = {'20', '5000', '10000', '15000', '20000'};
            app.f4.ValueChangingFcn = createCallbackFcn(app, @f4ValueChanging, true);
            app.f4.Value = 5000;
            app.bw4 = uiknob(app.LeftPanel, 'continuous');
            app.bw4.Limits = [-5 5];
            app.bw4.ValueChangingFcn = createCallbackFcn(app, @bw4ValueChanging, true);
            app.bw4.Value = 1;
            app.DropDown_4Label = uilabel(app.LeftPanel);
            app.DropDown_4Label.Text = 'Drop Down';
            app.filter4 = uidropdown(app.LeftPanel);
            app.filter4.Items = {'All pass', 'Low pass', 'High pass', 'Band pass ', 'Band stop', 'Low shelf', 'High shelf', 'Peaking EQ'};
            app.filter4.ValueChangedFcn = createCallbackFcn(app, @filter4ValueChanged, true);
            app.filter4.Value = 'All pass';
            app.f5 = uiknob(app.LeftPanel, 'continuous');
            app.f5.Limits = [20 20000];
            app.f5.MajorTicks = [20 5000 10000 15000 20000];
            app.f5.MajorTickLabels = {'20', '5000', '10000', '15000', '20000'};
            app.f5.ValueChangingFcn = createCallbackFcn(app, @f5ValueChanging, true);
            app.f5.Value = 5000;
            app.bw5 = uiknob(app.LeftPanel, 'continuous');
            app.bw5.Limits = [-5 5];
            app.bw5.ValueChangingFcn = createCallbackFcn(app, @bw5ValueChanging, true);
            app.bw5.Value = 1;
            app.DropDown_5Label = uilabel(app.LeftPanel);
            app.DropDown_5Label.Text = 'Drop Down';
            app.filter5 = uidropdown(app.LeftPanel);
            app.filter5.Items = {'All pass', 'Low pass', 'High pass', 'Band pass ', 'Band stop', 'Low shelf', 'High shelf', 'Peaking EQ'};
            app.filter5.ValueChangedFcn = createCallbackFcn(app, @filter5ValueChanged, true);
            app.filter5.Value = 'All pass';
            app.PLAYButton = uibutton(app.LeftPanel, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.PAUSEButton = uibutton(app.LeftPanel, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Text = 'PAUSE';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'EQ';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Real time audio replay')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ChangeTrackDropDownLabel = uilabel(app.RightPanel);
            app.ChangeTrackDropDownLabel.Text = 'Change Track';
            app.ChangeTrackDropDown = uidropdown(app.RightPanel);
            app.ChangeTrackDropDown.Items = {'Exemplu7', 'interface', 'Baby again', 'Sunflower', 'Killa Fonic', 'Eminem', 'What is love'};
            app.ChangeTrackDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangeTrackDropDownValueChanged, true);
            app.ChangeTrackDropDown.Value = 'Exemplu7';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.f1.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.PLAYButton.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eq_bun

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end